classdef ROICrop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROICropUIFigure           matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        SelectedFileLabel         matlab.ui.control.Label
        SelectedFolderLabel       matlab.ui.control.Label
        PreviewButton             matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        Step2SelectFileLabel      matlab.ui.control.Label
        Step1SelectfolderLabel    matlab.ui.control.Label
        ListallfilesButton        matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        SaveButton                matlab.ui.control.Button
        Step5SaveCroppedImgLabel  matlab.ui.control.Label
        CropButton                matlab.ui.control.Button
        Step4CropimgLabel         matlab.ui.control.Label
        CreateResetButton         matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        WidthEditField            matlab.ui.control.NumericEditField
        WidthEditFieldLabel       matlab.ui.control.Label
        HeightEditField           matlab.ui.control.NumericEditField
        HeightEditFieldLabel      matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        Step3DecideROIsizeLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
%         Data
%         Filelist
%         Selecttype
%         View

        dataname
        c1list
        totalz
        SelectRow
        merge_img
        roiobj
        ROI
        mytimer
        valuechange
        merge_img_cp
        dataidx
    end
    
    methods (Access = private)
        function resetTimer(app)
            listOfTimers = timerfindall;
            if ~isempty(listOfTimers)
                delete(listOfTimers(:));
            end
            app.mytimer = timer('Period', 1, 'ExecutionMode', 'fixedRate', ...
                'TasksToExecute', inf, 'TimerFcn', {@(~,~)updateROI(app)});
        end

        function  SelectFolder (app)
            if exist('tifflist', 'var'); clear tifflist; end
            exclu = {'_crp'};
            tifflist = List_TIFFandTIF(exclu);
            app.c1list = tifflist(contains ({tifflist.name},{'c1'}));
%             if sum(contains ({tifflist.name},{'c2'}))>0
%                 c2list = tifflist(contains ({tifflist.name},{'c2'}));
%             end
            datagroup=zeros([size(app.c1list)],'uint8');
            app.dataname = string();
            for i = 1:length(app.c1list)
                forename =  strfind(app.c1list(i).name, 'z')-1;
                if i == 1
                    datacount = 1;
                    datagroup(i) = datacount;
                elseif i == length(app.c1list)
                    datagroup(i) = datacount;
                else
                    samefile = strcmp(app.c1list(i).name(1:forename), app.c1list(i+1).name(1:forename));
                    datagroup(i) = datacount;
                    if ~samefile
                        datacount = datacount + 1;
                    end
                    app.dataname(datacount) = app.c1list(i).name(1:forename);
                end
            end
            clear samefile forename
%             fprintf('Selected Folder: %s \n', app.c1list(i).folder);
            app.SelectedFolderLabel.Text = "Folder : " + app.c1list(i).folder;
            app.totalz= [];
            for datacount = 1:max(datagroup)
                app.totalz(datacount) = length(find(datagroup == datacount));
            end    
            
        end
        
        function DisplayinTable(app)
            if ~isempty(app.UITable.Data), clear app.UITable.Data;end
%             vars ={'Filename', 'total z', ''};
%             tb = app.UITable;
            Tbdata = [app.dataname; app.totalz]';
%             Tbdata
%             tb.Data = Tbdata;
            app.UITable.Data = Tbdata;
%             tb.SelectionType = 'row';
        end


        function LoadFile (app)
%             fprintf('Previewing file : %i \n',app.SelectRow);
            fprintf('Now Previewing file: %s \n', app.dataname(app.SelectRow));
%             filelist = struct2cell(app.c1list);
%             filelist = filelist(1,:);
            clear app.dataidx imgseq imgnum img_mat
            app.dataidx = find(contains({app.c1list.name},app.dataname(app.SelectRow)+"z"));
            img_mat = [];
%             delete(app, "merge_img");
            app.merge_img = {};
            for imgseq = 1:length(app.dataidx)
                imgnum = app.dataidx(imgseq);
                
                filename = app.c1list(imgnum).name;                
                filepath = strcat(app.c1list(imgnum).folder, '\');    
                fullfilepath = strcat(filepath,filename );

                app.merge_img{imgseq} = loadtiff(fullfilepath);
                img_mat = cat(4, img_mat, app.merge_img{imgseq});

            end
            fprintf('Creating an avg img as the last layer data for visualising cell boundary\n');
            avgimg = mean(img_mat, 4);
            app.merge_img{imgseq+1} = avgimg;      % calculate avg and put into last img
        end
            
              
%         function LoadData(app)
%             
%             if ~isfield(app,'Filelist')
%                 error('Please select single file or multiple files or folder'); 
%             end
%             if ~isfield(app.Filelist, 'filename') || ~isfield(app.Filelist, 'path')
%                error ('something wrong, please select again or restart app');
%             end
%             if isfield(app,'Data')
%                 if isfield(app.Data,'MC')
%                     app.Data = rmfield(app.Data, 'MC');
%                 end
%             end
%                         
%             for i = 1:1 %length(app.Filelist)
%                 if length(app.Filelist) == 1
%                   TiffData = bfopen_vPingYen(strcat(app.Filelist(i).path, app.Filelist(i).filename));
%                 else
%                   TiffData = bfopen_vPingYen(strcat(app.Filelist(i).path, app.Filelist(i).filename));
%                 end
%                 C = TiffData{1,1}(:,1);
%                 app.Data.MC = reshape(cat(2,C{:}),[512,512,numel(C)]);
%                 [app.Data.x, app.Data.y, ~]=size(app.Data.MC);
%                 
% %                 app.Data.Proj.Avg = mean(app.Data.MC,3);
% %                 app.Data.Proj.Medi = median(app.Data.MC,3); 
% %                 fprintf('finish %dth calculation\n', i );
% %                 clear TiffData C M
%                     
% %                 roi
%             end
%         end

        function Preview(app)  
            
            twimshow_PingYen({app.merge_img{:}},{[0 1000]});colormap jet
%             app.previewobj = gcf;

%             if exist ('app', 'var')
%                 if isfield(app,'Filelist')
%                     app = rmfield(app,'Filelist');
%                 end
%             end
%             inclusion = {'.tiff'};
% 
%             app.Selecttype = 1;
%             switch app.Selecttype
%                 case 1
%                     fprintf("select an example file\n");
%                     [filename, folder] = uigetfile(inclusion, 'Select MotionCorrected .tiff file');
%                     app.Filelist(1).filename = filename;
%                     app.Filelist(1).path = folder;
%                     
%                 case 2
%                     fprintf('select multiple files in same folder using "control"/"shift" button \n\n');
%                     [filename, folder] = uigetfile(inclusion, 'Select multiple MotionCorrected .tiff file', 'MultiSelect', 'on');
%                     for i = 1: length(filename)
%                         app.Filelist(i).filename = char(filename(i));
%                         app.Filelist(i).path = folder;
%                     end                    
%                 case 3
%                     fprintf("Select the root folder containing target files\n");
%                     Mctifflist = List_TIFFandTIF(inclusion);
%                     for i = 1: length(Mctifflist)
%                         app.Filelist(i).filename = Mctifflist(i).name;
%                         app.Filelist(i).path = Mctifflist(i).folder;
%                     end
%                     
%             end
%             clear filename folder inclusion Mctifflist

        end


        function updateROI(app)

%             fprintf('updating ROI \n %s', isempty(app.ROI));
            
%%% Note: dragging and inputing should be seperated


            if ~isempty(app.ROI)
                if app.valuechange == 1

                ROIx = floor(app.XEditField.Value);
                ROIy = floor(app.YEditField.Value);
                ROIw = floor(app.WidthEditField.Value);
                ROIh = floor(app.HeightEditField.Value);
                app.ROI.Position = [ROIx,ROIy,ROIw,ROIh];
                else
                app.XEditField.Value = floor(app.ROI.Position(1));
                app.YEditField.Value = floor(app.ROI.Position(2));
                app.WidthEditField.Value = floor(app.ROI.Position(3));
                app.HeightEditField.Value = floor(app.ROI.Position(4));
                
                end

            else
                app.XEditField.Value = 0;
                app.YEditField.Value = 0;
                app.WidthEditField.Value = 0;
                app.HeightEditField.Value = 0;
                
            end

%             drawnow;

% 
%                 if isfield(app.Data,'MC')
% %                     app.Data.ROI = drawrectangle('Parent', app.UIAxes,'Position',[32,32,448,448],...
% %                                                  'Color','r', 'LineWidth', 0.8, 'MarkerSize', 4);
%                     app.Data.ROI = drawrectangle('Parent', gca,'Position',[32,32,448,448],...
%                              'Color','r', 'LineWidth', 0.8, 'MarkerSize', 4);
%                     app.XEditField.Value = app.Data.ROI.Position(1);
%                     app.YEditField.Value = app.Data.ROI.Position(2);
%                     app.WidthEditField.Value = app.Data.ROI.Position(3);
%                     app.HeightEditField.Value = app.Data.ROI.Position(4);
%                 end
%             end
%             app.Data.ROI.Position(1) = app.XEditField.Value;
%             app.Data.ROI.Position(2) = app.YEditField.Value;
%             app.Data.ROI.Position(3) = app.WidthEditField.Value;
%             app.Data.ROI.Position(4) = app.HeightEditField.Value;
            

%             PreviewButonPushed(app)
            end

        
        function cropit(app)
            app.merge_img_cp={};
            if ~isempty(app.merge_img)
                ROIx = floor(app.ROI.Position(1));
                ROIy = floor(app.ROI.Position(2));
                ROIw = floor(app.ROI.Position(3));
                ROIh = floor(app.ROI.Position(4));
                for i = 1 : length(app.merge_img)
                    app.merge_img_cp{i} = app.merge_img{i}(ROIy+1:ROIy+ROIh,ROIx+1:ROIx+ROIw,:);
                end

                twimshow_PingYen({app.merge_img_cp{:}},{[0 1000]});colormap jet

%                 for i = 1 : length(app.merge_img)
%                     app.merge_img_cp{i} =app.merge_img{i}
%                 end
            end
            app.XEditField.Value = 0;
            app.YEditField.Value = 0;
            app.WidthEditField.Value = 0;
            app.HeightEditField.Value = 0;

%             
%             if isfield(app,'Data')
%                 if isfield(app.Data,'output')
%                     app.Data = rmfield(app.Data, 'output');
%                 end
%             end
%             idx = strfind (filename, '.tif');
%             outname = strcat ('', filename(1:idx-1), colstr, zstr, '.tiff');
%         
% %             output = strcat ('SingleZ\', filename(1:idx), sprintf('c%d',c), sprintf('p%d',z), '.tiff');
%             outputpath = strcat (folder, outname);
%             bfsave(uint16((matxyt)), outputpath); 


        end        
        
%         function ROICrop_View (app)
% %         function img = ROICrop_View(z, cmap, datasetNames, curImInd)
% %                 link_cmaps = false;
%                 z = {app.Data.MC};
% %                 if ~iscell(z), z={z}; end
%                 cmap = gray;
% %                 if ~exist('cmap','var') || isempty(cmap)
% %                      cmap{1}= [];
% %                 elseif ~iscell(cmap)
% %                     cmap= {cmap};
% %                 end
%                 
% %                 if (numel(cmap)==1), link_cmaps = true; end
%                 
%                 for m =1:numel(cmap)
%                     if isempty(cmap{m})
%                         cmap{m} = [min(z{m}(:)) max(z{m}(:))];
%                     end
%                 end
%                 
%                 if ~exist('datasetNames','var'), datasetNames=[]; end
%                 % fill in missing names with dataset indecies
%                 for k=(numel(datasetNames)+1):numel(z)
%                     datasetNames{k} = num2str(k);
%                 end
%                     
%                 if ~exist('curImInd','var'), curImInd = 1; end
%                 prevImInd = 1;
%                 hImage = [];
%                 hTitle = [];
%                 curFrameInd =1;
%                 SetCurrentImage(curImInd);
%             
%                 set(gcf, 'WindowKeyPressFcn',@KeyPress_proc);
%                 
%                 function KeyPress_proc(src,evnt)
%                     switch evnt.Key 
%                         case{'t'}
%                             SetCurrentImage(curImInd+1);
%                         case{'rightarrow'}
%                             SetCurrentFrame(curFrameInd+1);
%                         case{'leftarrow'}
%                             SetCurrentFrame(curFrameInd-1);
%                         case{'c'}
%                             imcontrast
%                         case{'r'}
%                             impixelregion
%                         case{'o'} % plot original traces(singal pixel) from multiple movie
%                             plotTrace(z,0,1)            
%                         case{'a'} % plot 5*5 'average' traces from multiple movie
%                             plotTrace(z,1,1)
%                         case{'g'} % plot 7*7 'gaussian' traces from multiple movie
%                             plotTrace(z,2,1)
%                         case{'m'} % plot 5*5 'average' traces from multiple points of the first movie
%                             plotTrace(z,1,0)
%                         case{'1','2','3','4','5','6','7','8','9','0'}
%                             ind=str2double(evnt.Character);
%                             if ind==0, ind=10; end
%                             if ind <= numel(z), SetCurrentImage(ind); end
%                     end
%                 end
%                 function SetCurrentImage(imIndex)
%                     if imIndex > numel(z), imIndex =1; end
%                     
%                     prevImInd = curImInd;    
%                     curImInd = imIndex;
%                     
%                     if ~link_cmaps && ~isempty(hImage)
%                         cmap{prevImInd} = get(gca,'CLim');
%                         set(gca,'CLim',cmap{curImInd});  
%                     end
%                     
%                     displayimage
%                 end
%                 function SetCurrentFrame(frameInd)
%                     if frameInd > size(z{curImInd},3) 
%                         frameInd = 1; 
%                     elseif frameInd < 1
%                         frameInd = size(z{curImInd},3); 
%                     end
%                     curFrameInd = frameInd;
%                     displayimage
%                 end
%                 function displayimage
%                     titleString = sprintf('%s Frame:%d', datasetNames{curImInd},curFrameInd);
%                     if isempty(hImage)
% %                         figure;
%                         hImage = imshow(z{curImInd}(:,:,curFrameInd), cmap{curImInd}, 'Parent', app.UIAxes);
%                         hTitle = title(app.UIAxes, titleString);
%                         return;
%                     end
%                     
%                     set(hImage, 'CData',z{curImInd}(:,:,curFrameInd));
%                     set(hTitle,'String', titleString);
%                 end 
% %                 img = hImage;
%             end


        function savecroppedimg(app)
%             app.dataidx = find(contains({app.c1list.name},app.dataname(app.SelectRow)+"z"));
            for imgseq = 1:length(app.dataidx)
                imgnum = app.dataidx(imgseq);
                
                filename = app.c1list(imgnum).name;                
                filepath = strcat(app.c1list(imgnum).folder, '\Cropd\');    
%                 fullfilepath = strcat(filepath,filename);

                idx = strfind (filename, '.ti');
                outputpath = strcat ( filename(1:idx-1),'_crp.tiff');
                outputfile = strcat (filepath, outputpath);

                saveastiff(cast(app.merge_img_cp{imgseq}, 'uint16'), outputfile);
                fprintf('Saving cropped img : %s \n', outputfile);
            end
            txtcont = [app.dataname(app.SelectRow); app.c1list(imgnum).folder; ...
                app.ROI.Position(1); app.ROI.Position(2); app.ROI.Position(3); app.ROI.Position(4)];
            writematrix(txtcont, strcat(filepath, app.dataname(app.SelectRow),'.txt'));
%             save(strcat(app.dataname(app.SelectRow),'.txt'), 'app.dataname(app.SelectRow)', 'app.ROI.Position', 'UTF-8');
            
            
            fprintf('Finish saving :) \n');


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resetTimer(app);

%             app.UIAxes.XAxis.Visible = 'off';
%             app.UIAxes.YAxis.Visible = 'off';
%             app.UIAxes.Title.Visible = 'off';
%             app.UIAxes.Visible = 'off';
%             if isfield(app,'Data')
%                 if isfield(app.Data,'ROI')
%                     app.Data = rmfield(app.Data, 'ROI');
%                 end
%             end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ROICropUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {475, 475};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {347, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ListallfilesButton
        function SelectRootFolderButtonPushed(app, event)
            SelectFolder(app)
            DisplayinTable(app)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            app.SelectRow = app.UITable.Selection;
%             fprintf('Now selecting file: %i \n',app.SelectRow);
            fprintf('Now selecting file: %s \n',app.dataname(app.SelectRow));
            
        end

        % Button pushed function: PreviewButton
        function PreviewButonPushed(app, event)

%             app.UITable.Enable = "off";
            
            close all;
            app.SelectedFileLabel.Text = "Now Previewing file : " + app.dataname(app.SelectRow);
            LoadFile(app);
            Preview(app);
%                 app.UITable.Enalbe = "on";

%             for app.dataidx = 1:datacount
%                 if 
%                     filename(app.dataidx) = 
%             end


%             ROICrop_View (app);

%             cla(app.UIAxes)
%             hold(app.UIAxes,'on')


%             fig = uifigure;
%             im = uiimage(fig,'Parent', app.RightPanel, 'Position', [21 23 430 435]);
% %             ax = uiaxes(fig,'Parent', app.RightPanel, 'Position', [21 23 430 435]);
%             im.ImageSource = imshow(img,'Parent',app.RightPanel, 'Position', [21 23 430 435]);
%             updateROI(app)
        end

        % Button pushed function: CreateResetButton
        function CreateResetButtonPushed(app, event)
            resetTimer(app);

%             app.roiobj = findobj(gcf, 'Type', 'images.roi');
%             if ~isempty(app.roiobj), delete(app.roiobj); end
            if ~isempty(app.ROI), delete(app.ROI);end
            app.ROI = drawrectangle('Position',[32,32,448,448],'Color','y', 'LineWidth', 0.8, 'MarkerSize', 4);
            start(app.mytimer);
%             updateROI(app);

        end

        % Value changed function: HeightEditField, WidthEditField, 
        % ...and 2 other components
        function ROIValueChanged(app, event)
            app.valuechange = 1;
            if ~isempty(app.ROI)
                updateROI(app); 
            app.valuechange = 0;
            end
            
%             if ~isempty(app.roiobj), start(app.mytimer); end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            resetTimer(app);
            fprintf('Stop updating ROI and Start previewing cropped img : %s \n',app.dataname(app.SelectRow));

            cropit(app);

            fprintf('Previewing the cropped img : %s \n',app.dataname(app.SelectRow));

%             app.Selecttype = 2;
%             selection (app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fprintf('Start saving cropped img : %s and its ROI.Position \n',app.dataname(app.SelectRow) );

            savecroppedimg(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROICropUIFigure = uifigure('Visible', 'off');
            app.ROICropUIFigure.Name = 'ROICrop';
            app.ROICropUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ROICropUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ListallfilesButton = uibutton(app.LeftPanel, 'push');
            app.ListallfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRootFolderButtonPushed, true);
            app.ListallfilesButton.Text = 'List all files';
            app.Step1SelectfolderLabel = uilabel(app.LeftPanel);
            app.Step1SelectfolderLabel.Text = {'Step 1'; 'Select folder'};
            app.Step2SelectFileLabel = uilabel(app.LeftPanel);
            app.Step2SelectFileLabel.Text = {'Step 2'; 'Select File'};
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'File'; 'total z'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.PreviewButton = uibutton(app.LeftPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.SelectedFolderLabel = uilabel(app.LeftPanel);
            app.SelectedFolderLabel.Text = 'Selected Folder';
            app.SelectedFileLabel = uilabel(app.LeftPanel);
            app.SelectedFileLabel.Text = 'Selected  File';
            app.RightPanel = uipanel(app.GridLayout);
            app.Step3DecideROIsizeLabel = uilabel(app.RightPanel);
            app.Step3DecideROIsizeLabel.Text = {'Step3'; 'Decide ROI size'};
            app.Panel = uipanel(app.RightPanel);
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @ROIValueChanged, true);
            app.YEditFieldLabel = uilabel(app.Panel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @ROIValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.Panel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.Panel, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @ROIValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.Panel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.Panel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @ROIValueChanged, true);
            app.CreateResetButton = uibutton(app.RightPanel, 'push');
            app.CreateResetButton.ButtonPushedFcn = createCallbackFcn(app, @CreateResetButtonPushed, true);
            app.CreateResetButton.Text = 'Create / Reset';
            app.Step4CropimgLabel = uilabel(app.RightPanel);
            app.Step4CropimgLabel.Text = {'Step4'; 'Crop img'};
            app.CropButton = uibutton(app.RightPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.Step5SaveCroppedImgLabel = uilabel(app.RightPanel);
            app.Step5SaveCroppedImgLabel.Text = {'Step5'; 'Save Cropped Img'};
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.ROICropUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROICrop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROICropUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROICropUIFigure)
        end
    end
end