classdef LaunchNonRigidMC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaunchNonRigidMotionCorrection  matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        c1asstaticButton                matlab.ui.control.RadioButton
        c2asstaticButton                matlab.ui.control.RadioButton
        usestatictoregisterdynamicCheckBox  matlab.ui.control.CheckBox
        RedmeansthedefaultvaluesaresetforonlyxandyLabel  matlab.ui.control.Label
        UdontneedtochangeparametersPanel  matlab.ui.container.Panel
        upd_templateEditField           matlab.ui.control.EditField
        upd_templateEditFieldLabel      matlab.ui.control.Label
        print_msgEditField              matlab.ui.control.EditField
        print_msgEditFieldLabel         matlab.ui.control.Label
        mem_batch_sizeEditFieldLabel    matlab.ui.control.Label
        mem_filenameEditField           matlab.ui.control.EditField
        mem_filenameEditFieldLabel      matlab.ui.control.Label
        memmapEditField                 matlab.ui.control.EditField
        memmapEditFieldLabel            matlab.ui.control.Label
        add_valueEditFieldLabel         matlab.ui.control.Label
        methodEditField                 matlab.ui.control.EditField
        methodEditFieldLabel            matlab.ui.control.Label
        use_windowingEditField          matlab.ui.control.EditField
        use_windowingEditFieldLabel     matlab.ui.control.Label
        window_lengthEditFieldLabel     matlab.ui.control.Label
        col_shiftEditFieldLabel         matlab.ui.control.Label
        correct_bidirEditField          matlab.ui.control.EditField
        correct_bidirEditFieldLabel     matlab.ui.control.Label
        bitsizeEditFieldLabel           matlab.ui.control.Label
        output_typeEditField            matlab.ui.control.EditField
        output_typeEditFieldLabel       matlab.ui.control.Label
        shiftmethodEditField            matlab.ui.control.EditField
        mem_batch_sizeEditField         matlab.ui.control.NumericEditField
        add_valueEditField              matlab.ui.control.NumericEditField
        window_lengthEditField          matlab.ui.control.NumericEditField
        col_shiftEditField              matlab.ui.control.NumericEditField
        bitsizeEditField                matlab.ui.control.NumericEditField
        shiftmethodEditFieldLabel       matlab.ui.control.Label
        OtherparametersPanel            matlab.ui.container.Panel
        buffer_widthEditFieldLabel      matlab.ui.control.Label
        use_parallelEditField           matlab.ui.control.EditField
        use_parallelEditFieldLabel      matlab.ui.control.Label
        phase_flagEditField             matlab.ui.control.EditField
        phase_flagEditFieldLabel        matlab.ui.control.Label
        min_patch_sizeEditField         matlab.ui.control.EditField
        min_patch_sizeEditFieldLabel    matlab.ui.control.Label
        min_diffEditField               matlab.ui.control.EditField
        min_diffEditFieldLabel          matlab.ui.control.Label
        buffer_widthEditField           matlab.ui.control.NumericEditField
        bidir_usEditField               matlab.ui.control.NumericEditField
        bidir_usEditFieldLabel          matlab.ui.control.Label
        VerykeyparametersPanel          matlab.ui.container.Panel
        max_shiftEditField              matlab.ui.control.EditField
        max_shiftEditFieldLabel         matlab.ui.control.Label
        max_devEditField                matlab.ui.control.EditField
        max_devEditFieldLabel           matlab.ui.control.Label
        mot_ufEditField                 matlab.ui.control.EditField
        mot_ufEditField_2Label          matlab.ui.control.Label
        overlap_postEditField           matlab.ui.control.EditField
        overlap_postEditFieldLabel      matlab.ui.control.Label
        overlap_preEditField            matlab.ui.control.EditField
        overlap_preEditFieldLabel       matlab.ui.control.Label
        nFramesEditFieldLabel           matlab.ui.control.Label
        grid_sizeEditField              matlab.ui.control.EditField
        grid_sizeEditFieldLabel         matlab.ui.control.Label
        nFramesEditField                matlab.ui.control.NumericEditField
        iterEditField                   matlab.ui.control.NumericEditField
        iterEditFieldLabel              matlab.ui.control.Label
        us_facEditField                 matlab.ui.control.NumericEditField
        us_facEditFieldLabel            matlab.ui.control.Label
        init_batchEditField             matlab.ui.control.NumericEditField
        init_batchEditFieldLabel        matlab.ui.control.Label
        bin_widthEditField              matlab.ui.control.NumericEditField
        bin_widthEditFieldLabel         matlab.ui.control.Label
        PleasereadoriginalpaperformoredetailsLabel_2  matlab.ui.control.Label
        StartNonRigidMotionCorrectionButton  matlab.ui.control.Button
    end

    properties (Access = private)
        options

        StatList
        TargList
        ShiftList
        RegistCase
        data
        
        outputpath        
        MCoutput
        Shiftsoutput
    end

% Names = [
%     % dataset info
%     'd1                 ' % number of rows
%     'd2                 ' % number of cols
%     'd3                 ' % number of planes (for 3d imaging, default: 1)
%     % patches
%     'grid_size          ' % size of non-overlapping regions (default: [d1,d2,d3])
%     'overlap_pre        ' % size of overlapping region (default: [32,32,16])
%     'min_patch_size     ' % minimum size of patch (default: [32,32,16])    
%     'min_diff           ' % minimum difference between patches (default: [16,16,5])
%     'us_fac             ' % upsampling factor for subpixel registration (default: 20)
%     'mot_uf             ' % degree of patches upsampling (default: [4,4,1])
%     'max_dev            ' % maximum deviation of patch shift from rigid shift (default: [3,3,1])
%     'overlap_post       ' % size of overlapping region after upsampling (default: [32,32,16])
%     'max_shift          ' % maximum rigid shift in each direction (default: [15,15,5])
%     'phase_flag         ' % flag for using phase correlation (default: false)
%     'shifts_method      ' % method to apply shifts ('FFT','cubic','linear')
%     % template updating
%     'upd_template       ' % flag for online template updating (default: true)
%     'init_batch         ' % length of initial batch (default: 100)
%     'bin_width          ' % width of each bin (default: 200)
%     'buffer_width       ' % number of local means to keep in memory (default: 50)
%     'method             ' % method for averaging the template (default: {'median';'mean})
%     'iter               ' % number of data passes (default: 1)
%     'boundary           ' % method of boundary treatment 'NaN','copy','zero','template' (default: 'copy')
%     % misc
%     'add_value          ' % add dc value to data (default: 0)
%     'use_parallel       ' % for each frame, update patches in parallel (default: false)
%     'memmap             ' % flag for saving memory mapped motion corrected file (default: false)
%     'mem_filename       ' % name for memory mapped file (default: 'motion_corrected.mat')
%     'mem_batch_size     ' % batch size during memory mapping for speed (default: 5000)
%     'print_msg          ' % flag for printing progress to command line (default: true)
%     % plotting
%     'plot_flag          ' % flag for plotting results in real time (default: false)
%     'make_avi           ' % flag for making movie (default: false)
%     'name               ' % name for movie (default: 'motion_corrected.avi')
%     'fr                 ' % frame rate for movie (default: 30)
%     % output type
%     'output_type        ' % 'mat' (load in memory), 'memmap', 'tiff', 'hdf5', 'bin' (default:mat)
%     'h5_groupname       ' % name for hdf5 dataset (default: 'mov')
%     'h5_filename        ' % name for hdf5 saved file (default: 'motion_corrected.h5')
%     'tiff_filename      ' % name for saved tiff stack (default: 'motion_corrected.tif')
%     'output_filename    ' % name for saved file will be used if `h5_,tiff_filename` are not specified
%     % use windowing
%     'use_windowing      ' % flag for windowing data before fft (default: false)
%     'window_length      ' % length of window on each side of the signal as a fraction of signal length
%                            %    total length = length(signal)(1 + 2*window_length). (default: 0.5)
%     % bitsize for reading .raw files
%     'bitsize            ' % (default: 2 (uint16). other choices 1 (uint8), 4 (single), 8 (double))
%     % offset from bidirectional sampling
%     'correct_bidir      ' % check for offset due to bidirectional scanning (default: true)
%     'nFrames            ' % number of frames to average (default: 50)
%     'bidir_us           ' % upsampling factor for bidirectional sampling (default: 10)
%     'col_shift          ' % known bi-directional offset provided by the user (default: [])
%    ]; 

    methods (Access = private)
        
        function Listing(app)
            if exist('tifflist', 'var'); clear tifflist; end
            inclusion = {'shifts.mat', '.tiff'};
            exclusion = {'.ome.tiff', 'mc.tiff'};
            tifflist = ListAnyFile(inclusion, exclusion);

            if app.usestatictoregisterdynamicCheckBox.Value == 1
                choice = [app.c1asstaticButton.Value, app.c2asstaticButton.Value];
                template = find(choice == 1);
                switch template
                    case 1
                        app.StatList = tifflist(contains ({tifflist.name},{'c1'}));
                        app.TargList = tifflist(contains ({tifflist.name},{'c2'}));
                        fprintf('c1 chosen as static channel to register c2. \n');    
                    case 2
                        app.StatList = tifflist(contains ({tifflist.name},{'c2'}));
                        app.TargList = tifflist(contains ({tifflist.name},{'c1'}));
                        fprintf('c2 chosen as static channel to register c1. \n');    
                end
                app.ShiftList = app.StatList(contains ({app.StatList.name},{'shifts.mat'}));
            else
                if sum(contains ({tifflist.name},{'c2'})) > 0 
                    msgbox ("Data contains 2 channels, Please double check!!");
                    error ("Data contains 2 channels, Please double check!!");
                else
                    fprintf('no channel chosen as static channel, directly register the images. \n');
                    app.TargList = tifflist;
                end
            end
        end

        function FunctDecision(app)
            if length(app.TargList) < 1
                error("Please make sure there are data in the folder. \n");
            else
                if length(app.ShiftList) <1
                    if length(app.StatList) <1
                        app.RegistCase = 1;
                    else
                        app.RegistCase = 2;
                    end
                else
%                     if length(app.StatList) <1
%                         RegistCase = 2;
%                     else
%                         RegistCase = 1;
%                     end
                    app.RegistCase = 3;
                end                
            end

            switch app.RegistCase
                case 1 
                    fprintf ('Case %d: \n', app.RegistCase);
                    fprintf ("Start registration Target only. \n");
                    RegistAndOrSaveShifts (app);
                    SaveOptions(app);
                    SaveFluorStats(app);
                case 2
                    fprintf ("Case %d: \n", app.RegistCase);
                    fprintf ("There is no shift template from static channel are detected. \n");
                    fprintf ("Start register Static and Save shifts to regist Dynamic. \n");
                    RegistAndOrSaveShifts (app);
                    SaveOptions(app);
                    UseStatForTarg(app);
                    SaveFluorStats(app);
                case 3
                    fprintf ("Case %d: \n", app.RegistCase);
                    fprintf ("Shifts data are detected and used to regist Dynamic. \n");
                    UseStatForTarg(app);
                    SaveFluorStats(app);
            end

        end

        function C = CheckImgInver(~,imgdata)
            clear RawMed InvMed InverColour 

            RawMed = median(imgdata(:,:,1), 'all');
            InvMed = median(imcomplement(imgdata(:,:,1)),'all');
            InverColour = RawMed > InvMed;
            if InverColour
                C = imcomplement(imgdata);
            else
                C = imgdata;
            end
        end
        
        function convmat = ConverTxtToMat (~, input)
            input(~isstrprop(input, 'digit')) = ' ';
            convmat = str2double(strsplit(strtrim(input)));
        end

        function RegistAndOrSaveShifts (app)
            parpool(8,'IdleTimeout', Inf);gcp;

            % % VeryKeyParameters
            grid_size    = app.grid_sizeEditField.Value;
            overlap_pre  = app.overlap_preEditField.Value;
            overlap_post = app.overlap_postEditField.Value;
            mot_uf       = app.mot_ufEditField.Value;
            max_dev      = app.max_devEditField.Value;
            max_shift    = app.max_shiftEditField.Value;
            us_fac       = app.us_facEditField.Value;
            init_batch   = app.init_batchEditField.Value;
            bin_width    = app.bin_widthEditField.Value;
            nFrames      = app.nFramesEditField.Value;
            iter         = app.iterEditField.Value;

            % % OtherParameters
            min_patch_size = app.min_patch_sizeEditField.Value;
            min_diff       = app.min_diffEditField.Value;
            bidir_us       = app.bidir_usEditField.Value;
            buffer_width   = app.buffer_widthEditField.Value;
            phase_flag     = app.phase_flagEditField.Value;
            use_parallel   = app.use_parallelEditField.Value;
            
            % % 'U don't need to change' parameters will not be read, 
            % % and they will be set by the original SetParameters functoin

%%%%%%%%%%% % These Text edit fields for numeric matrix need a conversion step % %%%%%%%%%%%
                % grid_size
                % overlap_pre
                % overlap_post
                % mot_uf
                % max_dev
                % max_shift
                % min_patch_size
                % min_diff
            grid_size = ConverTxtToMat(app,grid_size);
            overlap_pre = ConverTxtToMat(app,overlap_pre);
            overlap_post = ConverTxtToMat(app,overlap_post);
            mot_uf = ConverTxtToMat(app,mot_uf);
            max_dev= ConverTxtToMat(app,max_dev);
            max_shift = ConverTxtToMat(app,max_shift);
            min_patch_size = ConverTxtToMat(app,min_patch_size);
            min_diff = ConverTxtToMat(app,min_diff);
%%%%%%%%%%% % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% % %%%%%%%%%%%
% fprintf('app.options.grid_size = %d %d %d, class: %s \n', grid_size, class(grid_size));
% fprintf('app.options.overlap_pre = %d %d %d, class: %s \n', overlap_pre, class(overlap_pre));
% fprintf('app.options.overlap_post = %d %d %d, class: %s \n', overlap_post, class(overlap_post));
% fprintf('app.options.mot_uf = %d %d %d, class: %s \n', mot_uf, class(mot_uf));
% fprintf('app.options.max_dev = %d %d %d, class: %s \n', max_dev, class(max_dev));
% fprintf('app.options.max_shift = %d %d %d, class: %s \n', max_shift, class(max_shift));
% fprintf('app.options.min_patch_size = %d %d %d, class: %s \n', min_patch_size, class(min_patch_size));
% fprintf('app.options.min_diff = %d %d %d, class: %s \n', min_diff, class(min_diff));
            
            if app.RegistCase == 1
                RegistList = app.TargList;
            else
                RegistList = app.StatList;
            end

            for i = 1 : length(RegistList)
                LoadImgAndSetOutput(app,RegistList,i);
                Y = single(app.data);
                Y = Y - min(Y(:));
                fprintf('size of Y = %d %d %d \n',size(Y));
                
                app.options = NoRMCorreSetParms('d1',size(Y ,1),'d2',size(Y,2),...
                'grid_size', grid_size,...
                'overlap_pre', overlap_pre, 'overlap_post', overlap_post, ...
                'mot_uf', mot_uf, 'max_dev', max_dev, 'max_shift', max_shift,...
                'us_fac', us_fac, 'init_batch', init_batch, ...
                'bin_width', bin_width, 'nFrames', nFrames, 'iter', iter, ...
                'min_patch_size', min_patch_size, 'min_diff', min_diff, ...
                'bidir_us', bidir_us, 'buffer_width', buffer_width, ...
                'phase_flag', phase_flag, 'use_parallel', use_parallel,...                
                'output_type', 'tiff', 'tiff_filename', app.MCoutput);
                fprintf('app.options.d1, d2, d3 = %d, %d, %d \n',app.options.d1,app.options.d2,app.options.d3);
                
                fprintf('%s: Nonrigid NC grid size is [%d %d] px, with max shift of [%d %d %d]px. \n',...
                        RegistList(i).name, grid_size(1), grid_size(2), max_shift);

                [~ ,shifts_g,~,~,~] = normcorre_batch_even_uint16_vPingYen(Y,app.options);
                if app.RegistCase == 2
                    fprintf("Shifts data from Static are saved to regist Dynamic later. \n");
                    varname = whos('shifts_g').name;
                    save (app.Shiftsoutput, varname);
                else
                    fprintf("Shifts data from Static are not saved. \n");
                end
            end
        end

        function LoadImgAndSetOutput(app,RegistList,i)
                filepath = strcat(RegistList(i).folder, '\');
                filename = RegistList(i).name;
                imgdata = loadtiff(strcat(filepath, filename));
                C = CheckImgInver(app,imgdata);
                app.data = C;
                idx = strfind (filename, '.ti');
                app.outputpath = strcat (filepath, 'MoCor\');
                MC_OutName = strcat(filename(1:idx-1),'_mc.tiff');
                app.MCoutput = strcat (app.outputpath , MC_OutName);
                
                Shifts_OutName = strcat (filename(1:idx-1),'_mc_shifts.mat');
                app.Shiftsoutput = strcat (app.outputpath , Shifts_OutName);                
        end

        function SaveOptions(app)
            optsoutfullpath = strcat(app.outputpath, 'options_', string(datetime('now', 'Format', 'yyyyMMdd')), '.mat');
            nms = {'d1', 'd2', 'd3', 'mem_filename', 'make_avi', 'name',...
                   'fr', 'h5_groupname', 'h5_filename', 'tiff_filename', ...
                   'output_filename'};
            for i =1 :length(nms)
                app.options = rmfield(app.options, nms{i});
            end
            optsoutput = app.options;
            varname = whos('optsoutput').name;
            save (optsoutfullpath, varname);
            fprintf("Options data for this batch are saved. \n");
        end

        function UseStatForTarg(app)
            RegistList = app.TargList;
            if length(app.ShiftList)< 1
                [parentDir,~,~] = fileparts (RegistList(1).folder);
                shiftlist = dir (fullfile(parentDir, '**\*.*'));
                shiftlist = shiftlist(contains ({shiftlist.name},{'shifts.mat'}));
            else
                shiftlist = app.ShiftList;
            end

            for i = 1 : length(RegistList)
                shiftsfile = strcat(shiftlist(i).folder,'\', shiftlist(i).name);
                load(shiftsfile)
                
                imgfile = strcat(RegistList(i).folder, '\', RegistList(i).name);
                imgdata = loadtiff(imgfile);
                C = CheckImgInver(app,imgdata);

                filepath = strcat(RegistList(i).folder, '\');
                filename = RegistList(i).name;
                MCpath = strcat (filepath,'MoCor\');
                idx = strfind (filename, '.ti');
                MC_OutName = strcat(filename(1:idx-1),'_mc.tiff');
                MC_file = strcat (MCpath , MC_OutName);

                Y = single(C);
                Y = Y - min(Y(:));
                MC_data =  zeros(size(Y),class(Y));
                
                d1=size(Y,1);
                d2=size(Y,2);
                add_value = app.add_valueEditField.Value;

                for T = 1:size(Y,3)
                    Yt= Y(:,:,T);
                    minY = min(Yt(:));
                    maxY = max(Yt(:));
                %     dim = size(Yt);
                %     nd = ndims(Yt);
                %     minY = min(Y(:,:,T));
                %     maxY = max(Y(:,:,T));
                %     dim = size(Y(:,:,T));
                    %  Yc = split_frame(Yt,patches);
                    % fftY = fft(fft(fft(Yc,[],1),[],2),[],3);
                %     nd = ndims(Y);
                    shifts_up = shifts_g(T).shifts_up;
                    shifts_up = imresize(shifts_up,[d1,d2]);
                %     shifts_up(2:2:end,:,2) = shifts_up(2:2:end,:,2) + options.col_shift;
                    Mf = imwarp(Yt,-cat(3,shifts_up(:,:,2),shifts_up(:,:,1)),'bicubic','FillValues',add_value); 
                    Mf(Mf<minY)=minY;
                    Mf(Mf>maxY)=maxY;
                    MC_data(:,:,T) = Mf;
                end
                
                % bfsave(((MC_data)), options.tiff_filename, 'XYZTC');
                % 
                saveastiff (uint16(MC_data),MC_file);
            end        
            fprintf('Finish registration from Static on Dynamic! \n');
        end
    
        function SaveFluorStats(app)
            fprintf('Plotting Max, Min, Avg, Median of fluorescence before and after registration! \n');
            if exist('tifflist', 'var'); clear tifflist; end
            if length(app.TargList)> 1
                [parentDir,~,~] = fileparts (app.TargList(1).folder);
                tifflist = dir (fullfile(parentDir, '**\*.tiff*'));
                tifflist = tifflist(~contains ({tifflist.name},{'ome'}));
            end
            for i =1:length(tifflist)
                imgfile = strcat(tifflist(i).folder, '\', tifflist(i).name);
                imgdata = loadtiff(imgfile);
    
%                 imgdata = reshape(cat(2,C{:}),[512,512,numel(C)]);
%                 imgdata = C;
                F=zeros([size(imgdata,3) 4]);
                Ftext = {'min'; 'median'; 'mean'; 'max'};

                for j = 1:size(imgdata,3)
                    m = imgdata(:,:,j);
                    m = m(:);
                    F(j,:) = [min(m), median(m), mean(m), max(m)];
                end
                %%
                figure(1);
                ax = axes;
                
                plot(ax, F(:,[1,2]), '--', 'LineWidth', 1.3);
                hold on;
                plot(ax, F(:,[3,4]), '-', 'LineWidth', 0.6);
                set(gca, 'box', 'off', 'GridLineStyle', ':', 'YGrid', 'on');
                ax.YAxis.Exponent = 0;
                ax.ColorOrder = [.1, .1, .1; .15, .15, .8;.8, .15, .15; .0, .7, .0];
                title(tifflist(i).name,'Interpreter','none');
                legend(Ftext([1 2 3 4]), 'Location', 'best');
                fig = gcf;
                fig.Position = [300, 300, 800, 600];
                
                
                idx = strfind (tifflist(i).name, '.ti');
                pngname = strcat (tifflist(i).name(1:idx-1),'.png');
                outputfile = strcat (tifflist(i).folder, '\', pngname);
                exportgraphics(fig,outputfile,'Resolution',600);
                close(figure(1));
            end
            fprintf('Finish Plotting Max, Min, Avg, Median of fluorescence! \n \n');
        end                         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartNonRigidMotionCorrectionButton
        function StartNonRigidMotionCorrectionButtonPushed(app, event)
            tic;
            delete(gcp('nocreate'));             % NoRMCorre calls Parrallel Computing, disable it before usage every time 
            
            Listing(app);
            FunctDecision(app)

            toc;
        end

        % Value changed function: usestatictoregisterdynamicCheckBox
        function usestatictoregisterdynamicCheckBoxValueChanged(app, event)
            value = app.usestatictoregisterdynamicCheckBox.Value;
            if value == 1
                app.ButtonGroup.Enable = 'on'; 
            else
                app.ButtonGroup.Enable = 'off'; 
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
%             ans = selectedButton
%             fprintf('Select %s as static channel \n', selectedButton);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaunchNonRigidMotionCorrection = uifigure('Visible', 'off');
            app.LaunchNonRigidMotionCorrection.Name = 'MATLAB App';
            app.StartNonRigidMotionCorrectionButton = uibutton(app.LaunchNonRigidMotionCorrection, 'push');
            app.StartNonRigidMotionCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartNonRigidMotionCorrectionButtonPushed, true);
            app.StartNonRigidMotionCorrectionButton.Text = {'Start'; 'Non-Rigid Motion Correction'};
            app.PleasereadoriginalpaperformoredetailsLabel_2 = uilabel(app.LaunchNonRigidMotionCorrection);
            app.PleasereadoriginalpaperformoredetailsLabel_2.Text = {'How to use: '; ''; 'A. Your data has single colour'; '=> ignore the checkbox, START and select the root folder'; ''; 'B. Your data has >1 colours'; '=> 1. make sure c1 and c2 folders in the same root folder'; '     2. start register the static colour first'; '     3. check the box and select the folder for dynamic channel'};
            app.VerykeyparametersPanel = uipanel(app.LaunchNonRigidMotionCorrection);
            app.VerykeyparametersPanel.Title = 'Very key parameters';
            app.bin_widthEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.bin_widthEditFieldLabel.Text = 'bin_width';
            app.bin_widthEditField = uieditfield(app.VerykeyparametersPanel, 'numeric');
            app.bin_widthEditField.Value = 32;
            app.init_batchEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.init_batchEditFieldLabel.Text = 'init_batch';
            app.init_batchEditField = uieditfield(app.VerykeyparametersPanel, 'numeric');
            app.init_batchEditField.Value = 100;
            app.us_facEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.us_facEditFieldLabel.Text = 'us_fac';
            app.us_facEditField = uieditfield(app.VerykeyparametersPanel, 'numeric');
            app.us_facEditField.Value = 50;
            app.iterEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.iterEditFieldLabel.Text = 'iter';
            app.iterEditField = uieditfield(app.VerykeyparametersPanel, 'numeric');
            app.iterEditField.Value = 2;
            app.nFramesEditField = uieditfield(app.VerykeyparametersPanel, 'numeric');
            app.nFramesEditField.Value = 50;
            app.grid_sizeEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.grid_sizeEditFieldLabel.Text = 'grid_size';
            app.grid_sizeEditField = uieditfield(app.VerykeyparametersPanel, 'text');
            app.grid_sizeEditField.Value = '[128,128,1]';
            app.nFramesEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.nFramesEditFieldLabel.Text = 'nFrames';
            app.overlap_preEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.overlap_preEditFieldLabel.Text = 'overlap_pre';
            app.overlap_preEditField = uieditfield(app.VerykeyparametersPanel, 'text');
            app.overlap_preEditField.Value = '[32,32,16]';
            app.overlap_postEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.overlap_postEditFieldLabel.Text = 'overlap_post';
            app.overlap_postEditField = uieditfield(app.VerykeyparametersPanel, 'text');
            app.overlap_postEditField.Value = '[32,32,16]';
            app.mot_ufEditField_2Label = uilabel(app.VerykeyparametersPanel);
            app.mot_ufEditField_2Label.Text = 'mot_uf';
            app.mot_ufEditField = uieditfield(app.VerykeyparametersPanel, 'text');
            app.mot_ufEditField.Value = '[4,4,1]';
            app.max_devEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.max_devEditFieldLabel.Text = 'max_dev';
            app.max_devEditField = uieditfield(app.VerykeyparametersPanel, 'text');
            app.max_devEditField.Value = '[3,3,1]';
            app.max_shiftEditFieldLabel = uilabel(app.VerykeyparametersPanel);
            app.max_shiftEditFieldLabel.Text = 'max_shift';
            app.max_shiftEditField = uieditfield(app.VerykeyparametersPanel, 'text');
            app.max_shiftEditField.Value = '[30,30,1]';
            app.OtherparametersPanel = uipanel(app.LaunchNonRigidMotionCorrection);
            app.OtherparametersPanel.Title = 'Other parameters';
            app.bidir_usEditFieldLabel = uilabel(app.OtherparametersPanel);
            app.bidir_usEditFieldLabel.Text = 'bidir_us';
            app.bidir_usEditField = uieditfield(app.OtherparametersPanel, 'numeric');
            app.bidir_usEditField.Value = 10;
            app.buffer_widthEditField = uieditfield(app.OtherparametersPanel, 'numeric');
            app.buffer_widthEditField.Value = 50;
            app.min_diffEditFieldLabel = uilabel(app.OtherparametersPanel);
            app.min_diffEditFieldLabel.Text = 'min_diff';
            app.min_diffEditField = uieditfield(app.OtherparametersPanel, 'text');
            app.min_diffEditField.Value = '[16,16,1]';
            app.min_patch_sizeEditFieldLabel = uilabel(app.OtherparametersPanel);
            app.min_patch_sizeEditFieldLabel.Text = 'min_patch_size';
            app.min_patch_sizeEditField = uieditfield(app.OtherparametersPanel, 'text');
            app.min_patch_sizeEditField.Value = '[16,16,1]';
            app.phase_flagEditFieldLabel = uilabel(app.OtherparametersPanel);
            app.phase_flagEditFieldLabel.Text = 'phase_flag';
            app.phase_flagEditField = uieditfield(app.OtherparametersPanel, 'text');
            app.phase_flagEditField.Value = 'false';
            app.use_parallelEditFieldLabel = uilabel(app.OtherparametersPanel);
            app.use_parallelEditFieldLabel.Text = 'use_parallel';
            app.use_parallelEditField = uieditfield(app.OtherparametersPanel, 'text');
            app.use_parallelEditField.Value = 'false';
            app.buffer_widthEditFieldLabel = uilabel(app.OtherparametersPanel);
            app.buffer_widthEditFieldLabel.Text = 'buffer_width';
            app.UdontneedtochangeparametersPanel = uipanel(app.LaunchNonRigidMotionCorrection);
            app.UdontneedtochangeparametersPanel.Title = '''U don''t need to change'' parameters';
            app.shiftmethodEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.shiftmethodEditFieldLabel.Text = 'shiftmethod';
            app.bitsizeEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'numeric');
            app.bitsizeEditField.Editable = 'off';
            app.bitsizeEditField.Enable = 'off';
            app.bitsizeEditField.Value = 2;
            app.col_shiftEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'numeric');
            app.col_shiftEditField.Editable = 'off';
            app.col_shiftEditField.Enable = 'off';
            app.window_lengthEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'numeric');
            app.window_lengthEditField.Editable = 'off';
            app.window_lengthEditField.Enable = 'off';
            app.window_lengthEditField.Value = 0.5;
            app.add_valueEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'numeric');
            app.add_valueEditField.Editable = 'off';
            app.add_valueEditField.Enable = 'off';
            app.mem_batch_sizeEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'numeric');
            app.mem_batch_sizeEditField.Editable = 'off';
            app.mem_batch_sizeEditField.Enable = 'off';
            app.mem_batch_sizeEditField.Value = 5000;
            app.shiftmethodEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'text');
            app.shiftmethodEditField.Editable = 'off';
            app.shiftmethodEditField.Enable = 'off';
            app.shiftmethodEditField.Value = 'FFT';
            app.output_typeEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.output_typeEditFieldLabel.Text = 'output_type';
            app.output_typeEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'text');
            app.output_typeEditField.Editable = 'off';
            app.output_typeEditField.Enable = 'off';
            app.output_typeEditField.Value = 'tiff';
            app.bitsizeEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.bitsizeEditFieldLabel.Text = 'bitsize';
            app.correct_bidirEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.correct_bidirEditFieldLabel.Text = 'correct_bidir';
            app.correct_bidirEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'text');
            app.correct_bidirEditField.Editable = 'off';
            app.correct_bidirEditField.Enable = 'off';
            app.correct_bidirEditField.Value = 'true';
            app.col_shiftEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.col_shiftEditFieldLabel.Text = 'col_shift';
            app.window_lengthEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.window_lengthEditFieldLabel.Text = 'window_length';
            app.use_windowingEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.use_windowingEditFieldLabel.Text = 'use_windowing';
            app.use_windowingEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'text');
            app.use_windowingEditField.Editable = 'off';
            app.use_windowingEditField.Enable = 'off';
            app.use_windowingEditField.Value = 'false';
            app.methodEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.methodEditFieldLabel.Text = 'method';
            app.methodEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'text');
            app.methodEditField.Editable = 'off';
            app.methodEditField.Enable = 'off';
            app.methodEditField.Value = '{''median'';''mean}';
            app.add_valueEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.add_valueEditFieldLabel.Text = 'add_value';
            app.memmapEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.memmapEditFieldLabel.Text = 'memmap';
            app.memmapEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'text');
            app.memmapEditField.Editable = 'off';
            app.memmapEditField.Enable = 'off';
            app.memmapEditField.Value = 'false';
            app.mem_filenameEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.mem_filenameEditFieldLabel.Text = 'mem_filename';
            app.mem_filenameEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'text');
            app.mem_filenameEditField.Editable = 'off';
            app.mem_filenameEditField.Enable = 'off';
            app.mem_filenameEditField.Value = ' ''motion_corrected.mat''';
            app.mem_batch_sizeEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.mem_batch_sizeEditFieldLabel.Text = 'mem_batch_size';
            app.print_msgEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.print_msgEditFieldLabel.Text = 'print_msg';
            app.print_msgEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'text');
            app.print_msgEditField.Editable = 'off';
            app.print_msgEditField.Enable = 'off';
            app.print_msgEditField.Value = 'true';
            app.upd_templateEditFieldLabel = uilabel(app.UdontneedtochangeparametersPanel);
            app.upd_templateEditFieldLabel.Text = 'upd_template';
            app.upd_templateEditField = uieditfield(app.UdontneedtochangeparametersPanel, 'text');
            app.upd_templateEditField.Editable = 'off';
            app.upd_templateEditField.Enable = 'off';
            app.upd_templateEditField.Value = 'true';
            app.RedmeansthedefaultvaluesaresetforonlyxandyLabel = uilabel(app.LaunchNonRigidMotionCorrection);
            app.RedmeansthedefaultvaluesaresetforonlyxandyLabel.Text = {'Red means the input must be [x, y, z] or [x, y] '; '(z will be determined default if not specified)'};
            app.Panel = uipanel(app.LaunchNonRigidMotionCorrection);
            app.usestatictoregisterdynamicCheckBox = uicheckbox(app.Panel);
            app.usestatictoregisterdynamicCheckBox.ValueChangedFcn = createCallbackFcn(app, @usestatictoregisterdynamicCheckBoxValueChanged, true);
            app.usestatictoregisterdynamicCheckBox.Text = 'use static to register dynamic';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Enable = 'off';
            app.c2asstaticButton = uiradiobutton(app.ButtonGroup);
            app.c2asstaticButton.Text = 'c2 as static';
            app.c2asstaticButton.Value = true;
            app.c1asstaticButton = uiradiobutton(app.ButtonGroup);
            app.c1asstaticButton.Text = 'c1 as static';
            % Show the figure after all components are created
            app.LaunchNonRigidMotionCorrection.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaunchNonRigidMC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaunchNonRigidMotionCorrection)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaunchNonRigidMotionCorrection)
        end
    end
end