classdef PipelineInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PipelineInterfaceUIFigure       matlab.ui.Figure
        LabelAtTop                      matlab.ui.control.Label
        Step0ExportOMETIFFfromFemtonicsMEScMESPanel  matlab.ui.container.Panel
        LabelForStep0ExportOMETIFF      matlab.ui.control.Label
        LaunchExportMESc_OMETIFFButton  matlab.ui.control.Button
        Step3CropROItoremovenoiseaftertheregistrationPanel  matlab.ui.container.Panel
        LabelForStep3CropROI            matlab.ui.control.Label
        LaunchinterfaceforROICropButton  matlab.ui.control.Button
        Step2RegistrationMotionCorrectionPanel  matlab.ui.container.Panel
        LaunchinterfaceforNonRigidMotionCorrectionButton  matlab.ui.control.Button
        DescForRegist                   matlab.ui.control.TextArea
        Step1ConvertometifftotiffPanel  matlab.ui.container.Panel
        Step1Label                      matlab.ui.control.Label
        SkipFirstFrameCheckBox          matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SplitChannelsandPlanesButton    matlab.ui.control.RadioButton
        SplitChannelsonlysimpleconvertifonlysinglechannelButton  matlab.ui.control.RadioButton
        StartConvertOMETIFF_TIFFButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: StartConvertOMETIFF_TIFFButton
        function StartConvertOMETIFF_TIFFButtonPushed(app, event)
            if app.SplitChannelsonlysimpleconvertifonlysinglechannelButton.Value == 1
                splitchoice = 'Split Channels only';
            else 
                splitchoice = 'Split Channels and Planes';
            end

            if app.SkipFirstFrameCheckBox.Value == 1
                skipfirst = 'Yes'; 
            else
                skipfirst = 'No';
            end
            fprintf("skip first frame = %s \n ", skipfirst);
            fprintf("splitchoice = %s \n ", splitchoice);
            
            ConvertOMETIFF_TIFF(splitchoice, skipfirst);
        end

        % Button pushed function: 
        % LaunchinterfaceforNonRigidMotionCorrectionButton
        function LaunchinterfaceforNonRigidMotionCorrectionButtonPushed(app, event)
%             if app.SkipFirstFrameCheckBox.Value == 1
%                 skipfirst = 'Yes'; 
%             else
%                 skipfirst = 'No';
%             end
%             fprintf("skipfirst = %s \n ", skipfirst);
            fprintf("Launching NonRigidMotionCorrection app interface \n ");
            LaunchNonRigidMC;


        end

        % Button pushed function: LaunchinterfaceforROICropButton
        function LaunchinterfaceforROICropButtonPushed(app, event)
            ROICrop;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PipelineInterfaceUIFigure = uifigure('Visible', 'off');
            app.PipelineInterfaceUIFigure.Name = 'PipelineInterface';
            app.Step1ConvertometifftotiffPanel = uipanel(app.PipelineInterfaceUIFigure);
            app.Step1ConvertometifftotiffPanel.Title = 'Step1 : Convert ometiff to tiff';
            app.StartConvertOMETIFF_TIFFButton = uibutton(app.Step1ConvertometifftotiffPanel, 'push');
            app.StartConvertOMETIFF_TIFFButton.ButtonPushedFcn = createCallbackFcn(app, @StartConvertOMETIFF_TIFFButtonPushed, true);
            app.StartConvertOMETIFF_TIFFButton.Text = {'Start'; 'ConvertOMETIFF_TIFF'};
            app.ButtonGroup = uibuttongroup(app.Step1ConvertometifftotiffPanel);
            app.SplitChannelsonlysimpleconvertifonlysinglechannelButton = uiradiobutton(app.ButtonGroup);
            app.SplitChannelsonlysimpleconvertifonlysinglechannelButton.Text = {'Split Channels only'; '(= simple convert if only single channel)'};
            app.SplitChannelsandPlanesButton = uiradiobutton(app.ButtonGroup);
            app.SplitChannelsandPlanesButton.Text = 'Split Channels and Planes';
            app.SplitChannelsandPlanesButton.Value = true;
            app.SkipFirstFrameCheckBox = uicheckbox(app.Step1ConvertometifftotiffPanel);
            app.SkipFirstFrameCheckBox.Text = 'Skip First Frame';
            app.Step1Label = uilabel(app.Step1ConvertometifftotiffPanel);
            app.Step1Label.Text = 'Select the root folder containing all the ometiff files you want to convert';
            app.Step2RegistrationMotionCorrectionPanel = uipanel(app.PipelineInterfaceUIFigure);
            app.Step2RegistrationMotionCorrectionPanel.Title = 'Step2 : Registration (Motion Correction)';
            app.DescForRegist = uitextarea(app.Step2RegistrationMotionCorrectionPanel);
            app.DescForRegist.Editable = 'off';
            app.DescForRegist.Value = {'All parameters will be shown in the interface'; ''; 'Please read original paper for more details: '; 'Non-Rigid Motion Correction'; 'Pnevmatikakis and Giovannucci 10.1016/j.jneumeth.2017.07.031'};
            app.LaunchinterfaceforNonRigidMotionCorrectionButton = uibutton(app.Step2RegistrationMotionCorrectionPanel, 'push');
            app.LaunchinterfaceforNonRigidMotionCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchinterfaceforNonRigidMotionCorrectionButtonPushed, true);
            app.LaunchinterfaceforNonRigidMotionCorrectionButton.Text = {'Launch interface for'; 'Non-Rigid Motion Correction'};
            app.Step3CropROItoremovenoiseaftertheregistrationPanel = uipanel(app.PipelineInterfaceUIFigure);
            app.Step3CropROItoremovenoiseaftertheregistrationPanel.Title = 'Step3 : Crop ROI to remove noise after the registration';
            app.LaunchinterfaceforROICropButton = uibutton(app.Step3CropROItoremovenoiseaftertheregistrationPanel, 'push');
            app.LaunchinterfaceforROICropButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchinterfaceforROICropButtonPushed, true);
            app.LaunchinterfaceforROICropButton.Text = {'Launch interface for'; 'ROICrop'};
            app.LabelForStep3CropROI = uilabel(app.Step3CropROItoremovenoiseaftertheregistrationPanel);
            app.LabelForStep3CropROI.Text = 'No need to enter any parameter';
            app.Step0ExportOMETIFFfromFemtonicsMEScMESPanel = uipanel(app.PipelineInterfaceUIFigure);
            app.Step0ExportOMETIFFfromFemtonicsMEScMESPanel.Title = 'Step0 : Export OMETIFF from Femtonics MESc/MES';
            app.LaunchExportMESc_OMETIFFButton = uibutton(app.Step0ExportOMETIFFfromFemtonicsMEScMESPanel, 'push');
            app.LaunchExportMESc_OMETIFFButton.Enable = 'off';
            app.LaunchExportMESc_OMETIFFButton.Text = {'Launch'; 'ExportMES(c)_OMETIFF'};
            app.LabelForStep0ExportOMETIFF = uilabel(app.Step0ExportOMETIFFfromFemtonicsMEScMESPanel);
            app.LabelForStep0ExportOMETIFF.Text = 'No this function for now, please export using Femtonics softwares';
            app.LabelAtTop = uilabel(app.PipelineInterfaceUIFigure);
            app.LabelAtTop.Text = {'Below two steps are suitable for time series data : xy-t or xyz-t'; 'If your data is xy or xyz only, not time series. please skip next two steps'};
            % Show the figure after all components are created
            app.PipelineInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PipelineInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PipelineInterfaceUIFigure)
        end
    end
end